classdef FracArea < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaskAxis                        matlab.ui.control.UIAxes
        IsolationAxis                   matlab.ui.control.UIAxes
        LoadmaskButton                  matlab.ui.control.Button
        ChoosefileButton                matlab.ui.control.Button
        MinsizeSliderLabel              matlab.ui.control.Label
        MinSizeSlider                   matlab.ui.control.Slider
        MaxsizeSliderLabel              matlab.ui.control.Label
        MaxSizeSlider                   matlab.ui.control.Slider
        IntensitycutoffSliderLabel      matlab.ui.control.Label
        IntensitySlider                 matlab.ui.control.Slider
        FilechosenEditFieldLabel        matlab.ui.control.Label
        FilePathBox                     matlab.ui.control.EditField
        LengthGuesspxTextAreaLabel      matlab.ui.control.Label
        LengthGuesspxTextArea           matlab.ui.control.TextArea
        RotationdegTextAreaLabel        matlab.ui.control.Label
        RotationdegTextArea             matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        MinSizeEditField                matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        MaxSizeEditField                matlab.ui.control.EditField
        OrigImageAxis                   matlab.ui.control.UIAxes
        DisksizeSliderLabel             matlab.ui.control.Label
        DiskSizeSlider                  matlab.ui.control.Slider
        EditField_3Label                matlab.ui.control.Label
        DiskSizeEditField               matlab.ui.control.EditField
        EditField_4Label                matlab.ui.control.Label
        IntensityEditField              matlab.ui.control.EditField
        ExportmaskButton                matlab.ui.control.Button
        MeancalculatedlengthpxTextAreaLabel  matlab.ui.control.Label
        MeancalculatedlengthpxTextArea  matlab.ui.control.TextArea
        MeanD1TextAreaLabel             matlab.ui.control.Label
        MeanD1TextArea                  matlab.ui.control.TextArea
        MeanD2TextAreaLabel             matlab.ui.control.Label
        MeanD2TextArea                  matlab.ui.control.TextArea
        MeanFractureAreaTextAreaLabel   matlab.ui.control.Label
        MeanFractureAreaTextArea        matlab.ui.control.TextArea
        ShowLengthsCheckBox             matlab.ui.control.CheckBox
        ShowongraphLabel                matlab.ui.control.Label
        NextimageButton                 matlab.ui.control.Button
        PreviousimageButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Image % The overall image
        Mask % The mask that is generated
        fileName % The filename
        fpath % The filepath
        numBands % The number of lines in the picture
        uiLength % The user's length guess
        imageSlices % Holds the seperated disks for processing
        checkValues % Values that need to be held outside of functions
        D1 % Disk line displacement
        D2 % Z-disk displacement
        Angles % Z-disk angles
        listOfImages % A list of all the images in the current directory
        numberOfImages % Number of iamges in the directory
        imageIndex % Current index of displayed image
        lineHandles % Handles for all the length lines
    end
    
    methods (Access = private)
        
        function imageMask = MakeMask(~, Image, diskSize, maxIntensity, lowerLim, upperLim)
            filterImage2 = imbothat(Image, strel('disk', diskSize));
            
            % Use imadjust to saturate Z-disc bands
            filterImage3 = imadjust(filterImage2);
            
            % Now we create a black-and-white mask, to filter out anything that isn't
            % z-band.  First we remove everything below a certain intensity, then we
            % create a 8-way connection map.  These regions are then labelled, and only
            % areas that are between a certain size are kept.
            BWImage = filterImage3 >= maxIntensity;
            
            % Calculate connectedness
            CC = bwconncomp(BWImage, 8);
            S = regionprops(CC, 'Area');
            L = labelmatrix(CC);
            
            BWImage = ismember(L, find([S.Area] <= upperLim & [S.Area] >= lowerLim));
            % And so we have identified the pixels that we want to keep
            
            % This multiplies our black-and-white map onto our original image,
            % retrieving the z-discs.
            imageMask = uint8(BWImage.*double(filterImage3));
        end
        
        function bgRM = rmBG(~, imageData)
            rots  = [1 2 3 4];
            
            % Remove linear background saturation. Chosen linear method, as
            % gaussian method tends to remove too much I- and A-band
            % information. Could be better, but I'm unsure how to work it.
            for i = 1:4
                imageData = rot90(imageData, rots(i));
                intensity = double(imageData(:, 1).');
                index = 1:length(imageData(:, 1));
                p = polyfit(index, intensity, 1);
                yfit = p(1) * index + p(2);
                adjust = yfit(1);
                yfit(length(yfit)/2:end) = yfit(length(yfit)/2:end) - yfit(length(yfit)/2);
                yfit(1:length(yfit)/2) = yfit(length(yfit)/2) - fliplr(yfit(1:length(yfit)/2)) + adjust;
                
                totalfit = repmat(yfit.', 1, length(imageData(1,:)));
                imageData = uint8(double(imageData) - totalfit*1);
            end
            bgRM = imageData;
        end
        
        function plotMask(app)
            % Display image and stretch to fill axes
            I = imshow(app.Mask, 'Parent', app.MaskAxis);
            app.MaskAxis.XLim = [0 2048];
            app.MaskAxis.YLim = [0 2048];
        end
        
        function plotIsolation(app)
            % Display image and stretch to fill axes
            I = imshow(app.Mask, 'Parent', app.IsolationAxis);
            app.IsolationAxis.XLim = [0 2048];
            app.IsolationAxis.YLim = [0 2048];
        end
        
        function FracArea = calculateFracArea(app)
            app.numBands = [];
            app.uiLength = [];
            app.imageSlices = [];
            app.checkValues = [];
            app.D1 = [];
            app.D2= [];
            if exist([app.fileName '/imageMask_rotated.tiff'], 'file')
                load([app.fileName '/rot.mat'], 'theta')
                app.RotationdegTextArea.Value = num2str(theta);
            else
                rotateImage(app);
            end
            if exist([app.fileName '/lengthGuess.mat'], 'file')
                load([app.fileName '/lengthGuess.mat'], 'lengthGuess')
                app.LengthGuesspxTextArea.Value = num2str(lengthGuess);
            else
                getLength(app);
            end
            
            isolatedLines = isolateLines(app);
            calcLength = calculateLengths(app, isolatedLines);
            drawnow()
            app.MeancalculatedlengthpxTextArea.Value = num2str(calcLength);
            seperateZLines(app, isolatedLines, calcLength)
            D1Angles = calculateD1(app);
            app.MeanD1TextArea.Value = num2str(mean(app.D1));
            drawnow()
            calculateD2(app, D1Angles);
            app.MeanD2TextArea.Value = num2str(mean(app.D2));
            drawnow()
            FracArea = app.D1 .* app.D2 .* app.Angles;
            app.MeanFractureAreaTextArea.Value = num2str(mean(FracArea));
            drawnow()
            writematrix(app.D1, [app.fileName '/D1.txt'], 'Delimiter',',')
            writematrix(app.D2, [app.fileName '/D2.txt'], 'Delimiter',',')
            writematrix(app.Angles, [app.fileName '/Angles.txt'], 'Delimiter',',')
            writematrix(FracArea, [app.fileName '/FracArea.txt'], 'Delimiter',',')
        end
        
        function mainAngles = calculateD1(app)
            mainAngles = zeros(1, length(app.imageSlices));
            app.D1 = [];
            for ic = 1:length(app.imageSlices)
                if isempty(app.imageSlices(ic).slice)
                    continue
                end
                
                app.imageSlices(ic).slice = app.imageSlices(ic).slice(~all(app.imageSlices(ic).slice == 0, 2), :);
                app.imageSlices(ic).slice = app.imageSlices(ic).slice(:, ~all(app.imageSlices(ic).slice == 0, 1));
                
                [x, y] = find(app.imageSlices(ic).slice > 0);
                
                X = [ones(length(x),1) x];
                b = X\y;
                compare = X * b;
                xfit = min(x):max(x);
                bestfit = xfit * b(2) + b(1);
                rise = (bestfit(end) - bestfit(1));
                run = xfit(end) - xfit(1);
                mainAngle = atan(rise/run);
                
                mainAngles(ic) = mainAngle;
                
                inipos = [xfit(1) bestfit(1)];
                bvec = [(xfit(end) - xfit(1)) (bestfit(end) - bestfit(1))];
                magb = sqrt(sum(bvec .* bvec));
                
                bhat = bvec ./ magb;
                
                % Use only the unique X's, because Z-disk pixels tend to overlap
                % with regards to x-position.
                uniX = unique(x);
                orthogDistanceVec = [];
                
                for Xc = 1:numel(uniX)
                    orthogDistance = zeros(1, 2);
                    xInd = find(x == uniX(Xc));
                    xTemp = x(xInd);
                    yTemp = y(xInd);
                    
                    nEnd = numel(xInd);
                    
                    for nc = 1:nEnd
                        i = xTemp(nc) - inipos(1);
                        j = yTemp(nc) - inipos(2);
                        
                        a = [i j];
                        
                        a1 = (dot(a, bvec, 2) * bvec) / (magb^2);
                        a2 = a - a1;
                        
                        a1_end = [(inipos(1) + a1(1)) (inipos(2) + a1(2))];
                        a_end = [(inipos(1) + a(1)) (inipos(2) + a(2))];
                        orthogDistance(nc) = sqrt((a_end(1) - a1_end(1))^2 + (a_end(2) - a1_end(2))^2);
                    end
                    
                    orthogDistance = abs(mean(orthogDistance));
                    orthogDistanceVec = [orthogDistanceVec orthogDistance];
                end
                app.D1 = [app.D1 mean(orthogDistanceVec)];
            end
        end
        
        function calculateD2(app, mainAngle)
            app.D2 = [];
            app.Angles = [];
            for ic = 1:length(app.imageSlices)
                if isempty(app.imageSlices(ic).slice)
                    continue
                end
                
                tempD2 = [];
                originalThetas = [];
                
                % Now to isolate the individual Z-disks, linearly regress them, and
                % compare the angle with the overall fit for the line of Z-disks.
                
                % Find connected regions and label them
                zDiskConnect = bwconncomp(app.imageSlices(ic).slice, 8);
                zDiskLabels = labelmatrix(zDiskConnect);
                
                for lc = 1:max(max(zDiskLabels))
                    zDisk = zeros(size(zDiskLabels));
                    zDisk(zDiskLabels == lc) = 1;
                    
                    zDisk = zDisk(~all(zDisk == 0, 2), :);
                    zDisk = zDisk(:, ~all(zDisk == 0, 1));
                    
                    % If the Z-disk is too small, do not regress.
                    if numel(zDisk) <= 10
                        continue
                    end
                    
                    % Rotate Z-disk to vertical orientation, without user input.
                    th = 0;
                    check = 0;
                    [~, originalTheta, ~] = rotateZdisk(app, zDisk, 0);
                    % This check tries to get the indices as close to a 45 degree incline as
                    % possible, for the best linear regression.
                    while check == 0
                        [zDiskRot, ~, newTh] = rotateZdisk(app, zDisk, th);
                        if newTh <= pi/4
                            check = check + 1;
                        else
                            if newTh >= pi/4
                                th = th - pi/8;
                            elseif newTh >= pi
                                th = th - 5*pi/8;
                            elseif newTh >= 3*pi/4
                                th = th - 9*pi/8;
                            else
                            end
                        end
                    end
                    tempD2 = [tempD2 calculateStraightness(app, zDiskRot)];
                    originalThetas = [originalThetas originalTheta];
                end
                angleDifference = abs(mainAngle(ic) - originalThetas);
                app.D2 = [app.D2 mean(tempD2)];
                app.Angles = [app.Angles mean(1 - rad2deg(angleDifference)/90)];
            end
        end
        
        function [out, origTh, newTh] = rotateZdisk(~, zDisk, th)
            %Function that takes input zDisk and rotates it into a vertical
            %alignment.
            
            %Find the indices of the Z-disk, and perform linear regression.
            [y, x] = find(zDisk > 0);
            
            %Add random rotation to enable better linear regression.
            [TH, R] = cart2pol(x, y);
            TH = TH + th;
            newTh = mean(TH);
            [x, y] = pol2cart(TH, R);
            
            if size(x, 1) == 1
                x = x.';
                y = y.';
            end
            
            X = [ones(length(x),1) x];
            fit = X\y;
            
            %Create the triangle
            bestfit = x * fit(2) + fit(1);
            a = (x(end) - x(1));
            b = (bestfit(end) - bestfit(1));
            %     c = sqrt(a^2 + b^2);
            
            %Calculate angle of line of best fit
            origTh =  atan(b/a);
            
            %Convert to polar coordinates
            %     [TH, R] = cart2pol(x, y);
            % %     TH = TH - pi/2 - theta;
            %     [x, y] = pol2cart(TH, R);
            %
            x = x - min(x) + 1;
            y = y - min(y) + 1;
            
            out = [x y];
        end
        
        function out = calculateStraightness(~, zDisk)
            %Calculates the straightness factor of a given Z-disc.  Only built for
            %single Z-discs.
            %Reconstruct image matrix
            
            x = zDisk(:, 1);
            y = zDisk(:, 2);
            X = [ones(length(x),1) x];
            b = X\y;
            
            xfit = min(x):max(x);
            bestfit = xfit * b(2) + b(1);
            
            inipos = [xfit(1) bestfit(1)];
            bvec = [(xfit(end) - xfit(1)) (bestfit(end) - bestfit(1))];
            magb = sqrt(sum(bvec .* bvec));
            
            uniX = unique(x);
            
            for Xc = 1:numel(uniX)
                orthog_dis = zeros(1, 2);
                xInd = find(x == uniX(Xc));
                xTemp = x(xInd);
                yTemp = y(xInd);
                
                nEnd = numel(xInd);
                
                for nc = 1:nEnd
                    i = xTemp(nc) - inipos(1);
                    j = yTemp(nc) - inipos(2);
                    
                    a = [i j];
                    
                    a1 = (dot(a, bvec, 2) * bvec) / (magb^2);
                    a2 = a - a1;
                    
                    a1_end = [(inipos(1) + a1(1)) (inipos(2) + a1(2))];
                    a_end = [(inipos(1) + a(1)) (inipos(2) + a(2))];
                    orthog_dis(nc) = sqrt((a_end(1) - a1_end(1))^2 + (a_end(2) - a1_end(2))^2);
                end
                
                out = mean(abs(orthog_dis));
                
            end
        end
        
        function rotateImage(app)
            % Currently, ginput isn't supported for UI axes in AppDesigner.
            % So make a new pop-up figure with handle, show mask, and use
            % ginput on that, then close the figure.
            fig1 = figure(1);
            
            % Use complement so that ginput crosshairs are visible.
            imshow(imcomplement(app.Mask))
            
            [xtri, ytri] = ginput(2);
            
            % Simple geometry.
            xlen = xtri(2) - xtri(1);
            ylen = ytri(2) - ytri(1);
            theta = atand(xlen / ylen);
            app.RotationdegTextArea.Value = num2str(theta);
            save([app.fileName '/rot.mat'], 'theta')
            
            % Rotate to vertical using -theta
            app.Mask = imrotate(app.Mask,  - theta, 'bilinear', 'crop');
            
            % Write rotated mask to disk
            imwrite(app.Mask, [app.fileName '/imageMask_rotated.tiff'])
            
            close(fig1)
            plotIsolation(app);
        end
        
        function getLength(app)
            fig1 = figure(1);
            imshow(imcomplement(app.Mask))
            [xlen, ~] = ginput(2);
            lengthGuess = abs(xlen(2) - xlen(1));
            app.LengthGuesspxTextArea.Value = num2str(lengthGuess);
            save([app.fileName '/lengthGuess.mat'], 'lengthGuess')
            app.uiLength = lengthGuess;
            close(fig1)
        end
        
        function results = isolateLines(app)
            ydim = size(app.Mask, 1);
            numlines = 50;
            indices = zeros(ydim, numlines);
            app.uiLength = str2double(app.LengthGuesspxTextArea.Value);
            threshold = ceil(app.uiLength / exp(1));
            
            % Find peaks and their indices in each horizontal "line" in the picture.
            % Conglomerate peaks if they are too close together.
            for nc = 1:ydim
                % Define line to check
                checkline = app.Mask(nc, :);
                % Find all peaks along this line
                [~, ind] = findpeaks(double(checkline));
                
                % If there are no peaks, go to next line
                if isempty(ind)
                    continue
                end
                
                % Set check variable
                check = 1;
                
                while check ~= length(ind)
                    if abs(ind(check + 1) - ind(check)) < app.uiLength - threshold % This checks to see if the next peak resolve by findpeaks is within the length guess range. If it is, delete it.
                        ind(check + 1) = [];
                    elseif abs(ind(check + 1) - ind(check)) > app.uiLength - threshold
                        check = check + 1;
                    else
                        error('Something went wrong with the index conglomeration (line 178).')
                    end
                end
                
                % For some reason, some peaks that were later became
                % before??? This gets rid of them, but should debug that in
                % future.
                for ic = 1 : length(ind) - 1
                    if ind(ic + 1) < ind(ic)
                        ind(ic + 1) = [];
                    end
                end
                
                % Set boundary conditions.
                for ic = 1 : length(ind)
                    if ind(ic) - threshold < 1
                        leftind = 1;
                        rightind = ind(ic) + threshold;
                    elseif ind(ic) + threshold > size(app.Image, 1)
                        leftind = ind(ic) - threshold;
                        rightind = size(app.Image, 1);
                    else
                        leftind = ind(ic) - threshold;
                        rightind = ind(ic) + threshold;
                    end
                    % Set the actual index to be the average or middle
                    % index of line.
                    tempLine = checkline(leftind:rightind);
                    tempInd = find(tempLine > 0);
                    avInd = round(length(tempInd)/2);
                    ind(ic) = leftind - 1 + tempInd(avInd);
                end
                
                indices(nc, 1:length(ind)) = ind;
            end
            
            
            summed = sum(indices);
            
            % Only take columns of indices array that have values in them
            indices = indices(:, summed > 0);
            
            % Redefine app.numBands to actual numbers of z-lines
            app.numBands = size(indices, 2);
            
            % Take largest number of peaks, and use those values are starting points
            % There must be a better way to do this, but I haven't found it
            % yet.
            temp = indices;
            temp(temp > 0) = 1;
            temp = sum(temp, 2);
            [~, I] = max(temp);
            app.checkValues = indices(I(1), :);
            
            % Create copy of app.checkValues to avoid possible ambiguity in second half
            % of indexing.
            checkValues2 = app.checkValues;
            
            % Initialise empty matrix for sorted matrix
            sorted_indices = zeros(size(indices));
            
            % For each peak in starting values, check each column of indices and see if
            % it is within the threshold value. (NOTE: only works for "straight" lines
            % right now, will need to implement updating indices at some point)
            
            
            % This part starts at the chosen points above, and moves "up" the
            % picture, which is then added to the "bottom" part below.
            for ic = 1 : app.numBands
                for jc = fliplr(1:I(1))
                    compare_value = app.checkValues(ic);
                    if indices(jc, ic) == 0
                        continue
                    else
                        if indices(jc, ic) > compare_value - threshold && indices(jc, ic) < compare_value + threshold
                            sorted_indices(jc, ic) = indices(jc, ic);
                            app.checkValues(ic) = indices(jc, ic);
                        else
                            indices(jc, ic + 1 : end) = indices(jc, ic : end - 1);
                            indices(jc, ic) = 0;
                        end
                    end
                end
            end
            
            % Reset app.checkValues for bottom part of the indexing.
            app.checkValues = checkValues2;
            
            % This portion looks for indices past the starting, "most lines"
            % indices chosen above.
            for ic = 1 : app.numBands
                for jc = I(1):size(indices, 1)
                    compare_value = app.checkValues(ic);
                    if indices(jc, ic) == 0
                        continue
                    else
                        if indices(jc, ic) > compare_value - threshold && indices(jc, ic) < compare_value + threshold
                            sorted_indices(jc, ic) = indices(jc, ic);
                            app.checkValues(ic) = indices(jc, ic);
                        else
                            indices(jc, ic + 1 : end) = indices(jc, ic : end - 1);
                            indices(jc, ic) = 0;
                        end
                    end
                end
            end
            
            % Check to see if sorted
            if indices == sorted_indices
            end
            
            hold(app.IsolationAxis, 'on')
            for ic = 1 : app.numBands
                ytemp = indices(:, ic);
                xtemp = 1:numel(ytemp);
                xtemp = xtemp(ytemp > 0);
                ytemp = ytemp(ytemp > 0);
                s = scatter(app.IsolationAxis, ytemp, xtemp, 20, '.');
            end
            results = sorted_indices;
        end
        
        function results = calculateLengths(app, indices)
            threshold = ceil(app.uiLength / exp(1));
            % Calculate average length
            banded_lengths = 0;
            allLengths = [];
            ind = 0;
            app.lineHandles = [];
            % Length index counter.
            
            % Calculate lengths by taking distance between points in neighbouring
            % lines.  Will work best when perfectly vertical, but error should be
            % scale with angle.
            for ic = 1 : app.numBands - 1
                lengths = [];
                for jc = 1 : size(indices, 1)
                    % If index in line ic is empty, continue.
                    if indices(jc, ic) == 0
                        continue
                        % If index in line ic+1 is empty, continue.
                    elseif indices(jc, ic + 1) == 0
                        continue
                        % If next index is inside of the user guess minus the threshold
                        % range, or outside of the user guess plus the threshold,
                        % continue
                    elseif abs(indices(jc, ic + 1) - indices(jc, ic)) > app.uiLength + threshold || abs(indices(jc, ic + 1) - indices(jc, ic)) < app.uiLength - threshold
                        continue
                    else
                        temp = abs(indices(jc, ic + 1) - indices(jc, ic));
                        lengths = [lengths; temp];
                    end
                end
                banded_lengths(ic, 1) = mean(lengths);
                if size(allLengths, 1) > numel(lengths)
                    allLengths = [allLengths [lengths; NaN(size(allLengths, 1) - numel(lengths), 1)]];
                elseif size(allLengths, 1) < numel(lengths)
                    allLengths = [[allLengths; NaN(numel(lengths) - size(allLengths, 1), size(allLengths, 2))] lengths];
                end
            end
            
            % Plot the bands on top of the previous image.
            for ic = 1 : app.numBands - 1
                for jc = 1:10:size(indices, 1)
                    if indices(jc, ic) == 0
                        continue
                    elseif indices(jc, ic + 1) == 0
                        continue
                    elseif abs(indices(jc, ic + 1) - indices(jc, ic)) > app.uiLength + threshold || abs(indices(jc, ic + 1) - indices(jc, ic)) < app.uiLength - threshold
                        continue
                    else
                        tempHandle = plot(app.IsolationAxis, [indices(jc, ic + 1) indices(jc, ic)], [jc jc], 'Color', [1,1,1,0.5], 'LineWidth', 2);
                        app.lineHandles = [app.lineHandles tempHandle];
                        ind = ind + 1;
                    end
                end
            end
 
            if app.ShowLengthsCheckBox.Value
                for ic = 1:length(app.lineHandles)
                    app.lineHandles(ic).Visible = 'on';
                end
            else
                for ic = 1:length(app.lineHandles)
                    app.lineHandles(ic).Visible = 'off';
                end
            end

            
            results = mean(banded_lengths(:));
            writematrix(allLengths, [app.fileName '/Lengths.txt'], 'Delimiter',',')
        end
        
        function seperateZLines(app, indices, adjusted_length)
            xdim = size(app.Mask, 1);
            ydim = size(app.Mask, 1);
            % Isolate bands into new variables, taking into account the edges cases
            % of the image.
            for ic = 1 : length(app.checkValues)
                for jc = 1 : ydim
                    if indices(jc, ic) == 0
                        continue
                    else
                        starter_ind(ic, jc) = indices(jc, ic) - ceil(adjusted_length/4);
                        if starter_ind(ic, jc) <= 0
                            starter_ind(ic, jc) = 1;
                        end
                        ender_ind(ic, jc) = indices(jc, ic) + ceil(adjusted_length/4);
                        if ender_ind(ic, jc) > xdim
                            ender_ind(ic, jc) = xdim;
                        end
                        index_vector = starter_ind(ic, jc) : ender_ind(ic, jc);
                        temp = app.Mask(jc, index_vector);
                        bands(jc, index_vector, ic) = temp;
                    end
                end
            end
           
            
            % Put into imageSlices.
            for ic = 1:size(starter_ind, 1)
                temp = bands(:, :, ic);
                temp_sum = sum(temp);
                nonzero_inds = find(temp_sum > 0, 1) : find(temp_sum > 0, 1, 'last');
                app.imageSlices(ic).slice = bands(:, nonzero_inds, ic);
            end
        end
        
        function imageChosen(app)
            if exist([app.fileName '/imageMask.tiff'], 'file')
               app.LoadmaskButton.Enable = 'on';
            end
            % Show the original image, for comparison. Not sure if strictly
            % necessary, but more information is rarely a bad thing.
            I = imshow(app.Image, 'Parent', app.OrigImageAxis);
            app.OrigImageAxis.XLim = [0 I.XData(2)];
            app.OrigImageAxis.YLim = [0 I.YData(2)];
            drawnow
            app.Mask = MakeMask(app, app.Image, round(app.DiskSizeSlider.Value), round(app.IntensitySlider.Value), round(app.MinSizeSlider.Value), round(app.MaxSizeSlider.Value));
            
            % Remove title, axes labels, and tick labels for figures
            xlabel(app.MaskAxis, []);
            ylabel(app.MaskAxis, []);
            app.MaskAxis.XAxis.TickLabels = {};
            app.MaskAxis.YAxis.TickLabels = {};
            
            xlabel(app.OrigImageAxis, []);
            ylabel(app.OrigImageAxis, []);
            app.OrigImageAxis.XAxis.TickLabels = {};
            app.OrigImageAxis.YAxis.TickLabels = {};
            
            xlabel(app.IsolationAxis, []);
            ylabel(app.IsolationAxis, []);
            app.IsolationAxis.XAxis.TickLabels = {};
            app.IsolationAxis.YAxis.TickLabels = {};
            
            plotMask(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            [fname, app.fpath] = uigetfile('*.tif', 'Select sarcomere images','MultiSelect','on');
            cd(app.fpath)
            app.fileName = fname(1:end-4);
            app.Image = imread(fullfile(app.fpath, fname));
            app.Image = rmBG(app, app.Image);
            
            app.listOfImages = dir('*.tif');
            app.numberOfImages = length(app.listOfImages);
            for ic = 1:app.numberOfImages
                if strcmp(app.listOfImages(ic).name, fname)
                    app.imageIndex = ic;
                end
            end
            app.FilePathBox.Value = fullfile(app.fpath, app.listOfImages(app.imageIndex).name);
            drawnow
            % Only enable these after an image is chosen, to avoid erroring
            % out of the app.
            app.DiskSizeSlider.Enable = 'on';
            app.IntensitySlider.Enable = 'on';
            app.MinSizeSlider.Enable = 'on';
            app.MaxSizeSlider.Enable = 'on';
            app.ExportmaskButton.Enable = 'on';
            app.NextimageButton.Enable = 'on';
            app.PreviousimageButton.Enable = 'on';
            
            imageChosen(app)
        end

        % Value changed function: DiskSizeSlider
        function DiskSizeSliderValueChanged(app, event)
            value = round(app.DiskSizeSlider.Value);
            app.Mask = MakeMask(app, app.Image, value, round(app.IntensitySlider.Value), round(app.MinSizeSlider.Value), round(app.MaxSizeSlider.Value));
            plotMask(app);
            app.DiskSizeEditField.Value = num2str(value);
        end

        % Value changing function: DiskSizeSlider
        function DiskSizeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.Mask = MakeMask(app, app.Image, changingValue, round(app.IntensitySlider.Value), round(app.MinSizeSlider.Value), round(app.MaxSizeSlider.Value));
            plotMask(app);
            app.DiskSizeEditField.Value = num2str(changingValue);
        end

        % Value changing function: IntensitySlider
        function IntensitySliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.Mask = MakeMask(app, app.Image, round(app.DiskSizeSlider.Value), changingValue, round(app.MinSizeSlider.Value), round(app.MaxSizeSlider.Value));
            plotMask(app);
            app.IntensityEditField.Value = num2str(changingValue);
        end

        % Value changing function: MinSizeSlider
        function MinSizeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.Mask = MakeMask(app, app.Image, round(app.DiskSizeSlider.Value), round(app.IntensitySlider.Value), changingValue, round(app.MaxSizeSlider.Value));
            plotMask(app);
            app.MinSizeEditField.Value = num2str(changingValue);
        end

        % Value changing function: MaxSizeSlider
        function MaxSizeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.Mask = MakeMask(app, app.Image, round(app.DiskSizeSlider.Value), round(app.IntensitySlider.Value), round(app.MinSizeSlider.Value), changingValue);
            plotMask(app);
            app.MaxSizeEditField.Value = num2str(changingValue);
        end

        % Value changed function: MinSizeSlider
        function MinSizeSliderValueChanged(app, event)
            
            
        end

        % Value changed function: MaxSizeSlider
        function MaxSizeSliderValueChanged(app, event)
            
            
        end

        % Value changed function: IntensitySlider
        function IntensitySliderValueChanged(app, event)
            
        end

        % Button pushed function: ExportmaskButton
        function ExportmaskButtonPushed(app, event)
            if ~exist(app.fileName, 'dir')
               mkdir(app.fileName)
            end
            imwrite(app.Mask, [app.fileName '/imageMask.tiff'])
            if strcmp(app.LoadmaskButton.Enable, 'off')
                app.LoadmaskButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadmaskButton
        function LoadmaskButtonPushed(app, event)
            if ~exist([app.fileName '/imageMask_rotated.tiff'], 'file')
                app.Mask = imread([app.fileName '/imageMask.tiff']);
            else
                app.Mask = imread([app.fileName '/imageMask_rotated.tiff']);
            end
            app.MeancalculatedlengthpxTextArea.Value = '';
            app.MeanD1TextArea.Value = '';
            app.MeanD2TextArea.Value = '';
            app.MeanFractureAreaTextArea.Value = '';
            plotIsolation(app);
            drawnow;
            calculateFracArea(app);
        end

        % Value changed function: DiskSizeEditField
        function DiskSizeEditFieldValueChanged(app, event)
            value = round(str2double(app.DiskSizeEditField.Value));
            if value < 0
                value = 0;
                app.DiskSizeEditField.Value = '0';
                app.DiskSizeSlider.Value = 0;
            elseif value > app.DiskSizeSlider.Limits(2)
                app.DiskSizeSlider.Limits(2) = value;
                app.DiskSizeEditField.Value = num2str(value);
                app.DiskSizeSlider.Value = value;
            end
            app.DiskSizeSlider.Value = value;
            app.DiskSizeEditField.Value = num2str(value);
            drawnow
            app.Mask = MakeMask(app, app.Image, value, round(app.IntensitySlider.Value), round(app.MinSizeSlider.Value), round(app.MaxSizeSlider.Value));
            plotMask(app);
            
        end

        % Value changed function: IntensityEditField
        function IntensityEditFieldValueChanged(app, event)
            value = round(str2double(app.IntensityEditField.Value));
            if value < 0
                value = 0;
                app.IntensityEditField.Value = '0';
                app.IntensitySlider.Value = 0;
            elseif value > 255
                value = 255;
                app.IntensitySlider.Limits(2) = 255;
                app.IntensityEditField.Value = '255';
                app.IntensitySlider.Value = 255;
            end
            app.IntensitySlider.Value = value;
            app.IntensityEditField.Value = num2str(value);
            drawnow
            app.Mask = MakeMask(app, app.Image, round(app.DiskSizeSlider.Value), value, round(app.MinSizeSlider.Value), round(app.MaxSizeSlider.Value));
            plotMask(app);
        end

        % Value changed function: MinSizeEditField
        function MinSizeEditFieldValueChanged(app, event)
            value = round(str2double(app.MinSizeEditField.Value));
            if value < 0
                value = 0;
                app.MinSizeEditField.Value = '0';
                app.MinSizeSlider.Value = 0;
            elseif value > app.MinSizeSlider.Limits(2)
                app.MinSizeSlider.Limits(2) = value;
                app.MinSizeEditField.Value = num2str(value);
                app.MinSizeSlider.Value = value;
            end
            app.MinSizeSlider.Value = value;
            app.MinSizeEditField.Value = num2str(value);
            drawnow
            app.Mask = MakeMask(app, app.Image, round(app.DiskSizeSlider.Value), round(app.IntensitySlider.Value), value, round(app.MaxSizeSlider.Value));
            plotMask(app);
        end

        % Value changed function: MaxSizeEditField
        function MaxSizeEditFieldValueChanged(app, event)
            value = round(str2double(app.MaxSizeEditField.Value));
            if value < 0
                value = 0;
                app.MaxSizeEditField.Value = '0';
                app.MaxSizeSlider.Value = 0;
            elseif value > app.MaxSizeSlider.Limits(2)
                app.MaxSizeSlider.Limits(2) = value;
                app.MaxSizeEditField.Value = num2str(value);
                app.MaxSizeSlider.Value = value;
            end
            app.MaxSizeSlider.Value = value;
            app.MaxSizeEditField.Value = num2str(value);
            drawnow
            app.Mask = MakeMask(app, app.Image, round(app.DiskSizeSlider.Value), round(app.IntensitySlider.Value),  round(app.MinSizeSlider.Value), value);
            plotMask(app);
            
        end

        % Button pushed function: PreviousimageButton
        function PreviousimageButtonPushed(app, event)
            app.imageIndex = app.imageIndex - 1;
            if app.imageIndex < 1
                app.imageIndex = app.numberOfImages;
            end
            
            fname = app.listOfImages(app.imageIndex).name;
            
            app.fileName = fname(1:end-4);
            app.Image = imread(fullfile(app.fpath, fname));
            app.Image = rmBG(app, app.Image);
            app.FilePathBox.Value = fullfile(app.fpath, app.listOfImages(app.imageIndex).name);
            app.RotationdegTextArea.Value = '';
            app.LengthGuesspxTextArea.Value = '';
            app.MeancalculatedlengthpxTextArea.Value = '';
            app.MeanD1TextArea.Value = '';
            app.MeanD2TextArea.Value = '';
            app.MeanFractureAreaTextArea.Value = '';
            cla(app.IsolationAxis)
            
            drawnow
            imageChosen(app)
        end

        % Button pushed function: NextimageButton
        function NextimageButtonPushed(app, event)
            app.imageIndex = app.imageIndex + 1;
            if app.imageIndex > app.numberOfImages
                app.imageIndex = 1;
            end
            
            fname = app.listOfImages(app.imageIndex).name;
            
            app.fileName = fname(1:end-4);
            app.Image = imread(fullfile(app.fpath, fname));
            app.Image = rmBG(app, app.Image);
            app.FilePathBox.Value = fullfile(app.fpath, app.listOfImages(app.imageIndex).name);
            app.RotationdegTextArea.Value = '';
            app.LengthGuesspxTextArea.Value = '';
            app.MeancalculatedlengthpxTextArea.Value = '';
            app.MeanD1TextArea.Value = '';
            app.MeanD2TextArea.Value = '';
            app.MeanFractureAreaTextArea.Value = '';
            cla(app.IsolationAxis)
            
            drawnow
            imageChosen(app)
        end

        % Value changed function: ShowLengthsCheckBox
        function ShowLengthsCheckBoxValueChanged(app, event)
            value = app.ShowLengthsCheckBox.Value;
            
            if value
                for ic = 1:length(app.lineHandles)
                    app.lineHandles(ic).Visible = 'on';
                end
            else
                for ic = 1:length(app.lineHandles)
                    app.lineHandles(ic).Visible = 'off';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MaskAxis = uiaxes(app.UIFigure);
            title(app.MaskAxis, 'Current mask state')
            xlabel(app.MaskAxis, '')
            ylabel(app.MaskAxis, '')
            app.MaskAxis.XTick = [];
            app.MaskAxis.YTick = [];
            app.IsolationAxis = uiaxes(app.UIFigure);
            title(app.IsolationAxis, 'Current output')
            xlabel(app.IsolationAxis, '')
            ylabel(app.IsolationAxis, '')
            app.IsolationAxis.XTick = [];
            app.IsolationAxis.YTick = [];
            app.LoadmaskButton = uibutton(app.UIFigure, 'push');
            app.LoadmaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmaskButtonPushed, true);
            app.LoadmaskButton.Enable = 'off';
            app.LoadmaskButton.Text = 'Load mask';
            app.ChoosefileButton = uibutton(app.UIFigure, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.MinsizeSliderLabel = uilabel(app.UIFigure);
            app.MinsizeSliderLabel.Text = 'Min size';
            app.MinSizeSlider = uislider(app.UIFigure);
            app.MinSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MinSizeSliderValueChanged, true);
            app.MinSizeSlider.ValueChangingFcn = createCallbackFcn(app, @MinSizeSliderValueChanging, true);
            app.MinSizeSlider.Enable = 'off';
            app.MinSizeSlider.Value = 50;
            app.MaxsizeSliderLabel = uilabel(app.UIFigure);
            app.MaxsizeSliderLabel.Text = 'Max size';
            app.MaxSizeSlider = uislider(app.UIFigure);
            app.MaxSizeSlider.Limits = [0 10000];
            app.MaxSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSizeSliderValueChanged, true);
            app.MaxSizeSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSizeSliderValueChanging, true);
            app.MaxSizeSlider.Enable = 'off';
            app.MaxSizeSlider.Value = 10000;
            app.IntensitycutoffSliderLabel = uilabel(app.UIFigure);
            app.IntensitycutoffSliderLabel.Text = 'Intensity cutoff';
            app.IntensitySlider = uislider(app.UIFigure);
            app.IntensitySlider.Limits = [0 255];
            app.IntensitySlider.MajorTicks = [0 255];
            app.IntensitySlider.ValueChangedFcn = createCallbackFcn(app, @IntensitySliderValueChanged, true);
            app.IntensitySlider.ValueChangingFcn = createCallbackFcn(app, @IntensitySliderValueChanging, true);
            app.IntensitySlider.MinorTicks = [31 63 95 127 159 191 223 255];
            app.IntensitySlider.Enable = 'off';
            app.IntensitySlider.Value = 200;
            app.FilechosenEditFieldLabel = uilabel(app.UIFigure);
            app.FilechosenEditFieldLabel.Text = 'File chosen';
            app.FilePathBox = uieditfield(app.UIFigure, 'text');
            app.FilePathBox.Editable = 'off';
            app.LengthGuesspxTextAreaLabel = uilabel(app.UIFigure);
            app.LengthGuesspxTextAreaLabel.Text = 'Length Guess (px)';
            app.LengthGuesspxTextArea = uitextarea(app.UIFigure);
            app.LengthGuesspxTextArea.Editable = 'off';
            app.RotationdegTextAreaLabel = uilabel(app.UIFigure);
            app.RotationdegTextAreaLabel.Text = 'Rotation (deg)';
            app.RotationdegTextArea = uitextarea(app.UIFigure);
            app.RotationdegTextArea.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.MinSizeEditField = uieditfield(app.UIFigure, 'text');
            app.MinSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinSizeEditFieldValueChanged, true);
            app.MinSizeEditField.Value = '50';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.MaxSizeEditField = uieditfield(app.UIFigure, 'text');
            app.MaxSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxSizeEditFieldValueChanged, true);
            app.MaxSizeEditField.Value = '10000';
            app.OrigImageAxis = uiaxes(app.UIFigure);
            title(app.OrigImageAxis, 'Original Image')
            xlabel(app.OrigImageAxis, '')
            ylabel(app.OrigImageAxis, '')
            app.OrigImageAxis.XTick = [];
            app.OrigImageAxis.YTick = [];
            app.DisksizeSliderLabel = uilabel(app.UIFigure);
            app.DisksizeSliderLabel.Text = 'Disk size';
            app.DiskSizeSlider = uislider(app.UIFigure);
            app.DiskSizeSlider.Limits = [0 50];
            app.DiskSizeSlider.ValueChangedFcn = createCallbackFcn(app, @DiskSizeSliderValueChanged, true);
            app.DiskSizeSlider.ValueChangingFcn = createCallbackFcn(app, @DiskSizeSliderValueChanging, true);
            app.DiskSizeSlider.Enable = 'off';
            app.DiskSizeSlider.Value = 20;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.DiskSizeEditField = uieditfield(app.UIFigure, 'text');
            app.DiskSizeEditField.ValueChangedFcn = createCallbackFcn(app, @DiskSizeEditFieldValueChanged, true);
            app.DiskSizeEditField.Value = '20';
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.IntensityEditField = uieditfield(app.UIFigure, 'text');
            app.IntensityEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityEditFieldValueChanged, true);
            app.IntensityEditField.Value = '200';
            app.ExportmaskButton = uibutton(app.UIFigure, 'push');
            app.ExportmaskButton.ButtonPushedFcn = createCallbackFcn(app, @ExportmaskButtonPushed, true);
            app.ExportmaskButton.Enable = 'off';
            app.ExportmaskButton.Text = 'Export mask';
            app.MeancalculatedlengthpxTextAreaLabel = uilabel(app.UIFigure);
            app.MeancalculatedlengthpxTextAreaLabel.Text = 'Mean calculated length (px)';
            app.MeancalculatedlengthpxTextArea = uitextarea(app.UIFigure);
            app.MeancalculatedlengthpxTextArea.Editable = 'off';
            app.MeanD1TextAreaLabel = uilabel(app.UIFigure);
            app.MeanD1TextAreaLabel.Text = 'Mean D1';
            app.MeanD1TextArea = uitextarea(app.UIFigure);
            app.MeanD1TextArea.Editable = 'off';
            app.MeanD2TextAreaLabel = uilabel(app.UIFigure);
            app.MeanD2TextAreaLabel.Text = 'Mean D2';
            app.MeanD2TextArea = uitextarea(app.UIFigure);
            app.MeanD2TextArea.Editable = 'off';
            app.MeanFractureAreaTextAreaLabel = uilabel(app.UIFigure);
            app.MeanFractureAreaTextAreaLabel.Text = 'Mean Fracture Area';
            app.MeanFractureAreaTextArea = uitextarea(app.UIFigure);
            app.MeanFractureAreaTextArea.Editable = 'off';
            app.ShowLengthsCheckBox = uicheckbox(app.UIFigure);
            app.ShowLengthsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLengthsCheckBoxValueChanged, true);
            app.ShowLengthsCheckBox.Text = '';
            app.ShowLengthsCheckBox.Value = true;
            app.ShowongraphLabel = uilabel(app.UIFigure);
            app.ShowongraphLabel.Text = 'Show on graph';
            app.NextimageButton = uibutton(app.UIFigure, 'push');
            app.NextimageButton.ButtonPushedFcn = createCallbackFcn(app, @NextimageButtonPushed, true);
            app.NextimageButton.Enable = 'off';
            app.NextimageButton.Text = 'Next image';
            app.PreviousimageButton = uibutton(app.UIFigure, 'push');
            app.PreviousimageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousimageButtonPushed, true);
            app.PreviousimageButton.Enable = 'off';
            app.PreviousimageButton.Text = 'Previous image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FracArea

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end